classdef stc_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GUIforSTASTCUIFigure  matlab.ui.Figure
        channelListBoxLabel   matlab.ui.control.Label
        channelListBox        matlab.ui.control.ListBox
        TabGroup              matlab.ui.container.TabGroup
        STATab                matlab.ui.container.Tab
        STA_Axes_temporal     matlab.ui.control.UIAxes
        STA_Axes_hist         matlab.ui.control.UIAxes
        STA_Panel_slice       matlab.ui.container.Panel
        STCTab                matlab.ui.container.Tab
        STC_Axes_ev           matlab.ui.control.UIAxes
        STC_Axes_hist         matlab.ui.control.UIAxes
        STC_Panel_sig_ev      matlab.ui.container.Panel
        STC_Panel_score       matlab.ui.container.Panel
        infoTab               matlab.ui.container.Tab
        infoTextArea          matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        channel_names
        selected_channel_index
        
%         stim             % input stim
%         spike_train      % measured spike response
        
        sta_all_channels
        sta_num_samples
        
        height
        width
        
        sampling_rate
        gridT
        
        %sta_num_samples
        %sta_result

        stc_u                       %                   1x156              8569872  cell         
        stc_ev                      %                   1x156               116776  cell                                                 
        stc_score                   %                   1x156            162471552  cell                                                 
        stc_significant_ev_idx      %                   1x156                18104  cell                                                 
  
        
    end
    
    methods (Access = private)

%         function plot_spike_train(app)
%             bar(app.spike_Axes, app.spike_train(:,app.selected_channel_index))
%         end
    
    
%         function run_sta(app) %, stim, spike_train, sta_num_samples)
%             % run STA
%             app.sta_result = calc_STA_and_STC(app.stim, app.spike_train(:,app.selected_channel_index), app.sta_num_samples);
%             
%             % plot
%             plot(app.STA_Axes_temporal, app.sta_result)
%             
%         end
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %% PLOTTING FUNCTIONS ARE HERE
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function plot_sta(app)

            sta = app.sta_all_channels{app.selected_channel_index};
            sta = reshape(sta, app.sta_num_samples, []);
            
            % plot temporal profile of STA
            ax = app.STA_Axes_temporal;
            grid = app.gridT*1000;
            plot(ax, grid, sta)
            
%             app.STA_Axes_temporal.XLabel = 'ms'
%             app.STA_Axes_temporal.YLabel = 'stim intensity'
            ax.YLim = [0 1];
            
            % plot histogram of STA
            ax = app.STA_Axes_hist;
            bins = 0:0.025:1;
            histogram(ax, sta(:), bins);

            % add info
            app.infoTextArea.Value = sprintf('[STA] mean=%.2f, std=%.2f', mean(sta(:)),std(sta(:)));
           
%           % plot spatial profile of STA   
            plot_sta_slices(app, app.STA_Panel_slice, sta)
        end

        function plot_sta_slices(app, fig, sta)
            
            [max_val, max_slice_idx] =  max(max(sta, [], 2));
            [min_val, min_slice_idx] =  min(min(sta, [], 2));
            
            num_slices = size(sta,1);

            r = 2;
            %r=floor(sqrt(num_slices));
            c=ceil(num_slices/r);
            
            fig.AutoResizeChildren = 'off';
            for cnt=1:num_slices
                ax = subplot(r,c,cnt,'Parent', fig);
                FLIP_XY = true;
                plot_sta_one_slice(app, ax, sta(cnt,:), FLIP_XY);
                
                % add title
                ax.Title.String = sprintf('%.0f ms',app.gridT(cnt)*1000);
                if cnt == max_slice_idx
                    ax.Title.String = [ax.Title.String  ' (pos. peak)'];
                end
                if cnt == min_slice_idx
                    ax.Title.String = [ax.Title.String  ' (neg. peak)'];
                end
                ax.Title.FontWeight = 'normal';
              
            end            
            
        end
        
        function plot_sta_one_slice(app, ax, slice, FLIP_XY)
            if FLIP_XY
                imagesc(reshape(slice, app.height, app.width)', 'Parent', ax)
            else
                imagesc(reshape(slice, app.height, app.width), 'Parent', ax)
            end
            ax.XLim = [1 app.width];
            ax.YLim = [1 app.height];
            ax.CLim = [0.2 0.8];            
            ax.Colormap = gray;
            
            axis(ax,'off')
            axis(ax,'xy')
            
        end
        
    
        function results = plot_stc(app)
            % get results
            ev = app.stc_ev{app.selected_channel_index};            
            stc_significant_ev_idx = app.stc_significant_ev_idx{app.selected_channel_index}
            
            % info: mean and std of eig. values
            str = sprintf('[STC] eig. value: mean=%.2f, std=%.2f', mean(ev), std(ev));
            app.infoTextArea.Value = [app.infoTextArea.Value; str];
            
            % plot eigen values            
            ax = app.STC_Axes_ev;
            plot(ax, ev, 'o:', 'MarkerSize', 5);
            if ~isempty(stc_significant_ev_idx)
                str1 = 'significant eig. vector idx=';
                str2 = sprintf('%d ', stc_significant_ev_idx);
                str = [str1 str2];
                app.infoTextArea.Value = [app.infoTextArea.Value; str];
            
                hold (ax, 'on')
                plot(ax, stc_significant_ev_idx, ev(stc_significant_ev_idx), '*r', 'MarkerSize', 10);
                hold (ax, 'off')
            end
            
            % plot histogram of eigen values
            ax = app.STC_Axes_hist;
%             bins = 0:0.025:1;
            histogram(ax, ev);
            
            
            %% plot eigen vectors
            fig = app.STC_Panel_sig_ev;
            
            num_sig_ev = length(stc_significant_ev_idx);
            if num_sig_ev >0
                fig.Visible = 'on';
                fig.AutoResizeChildren = 'off';
            
                u = app.stc_u{app.selected_channel_index};
                
                r = max(num_sig_ev,2);
                cnt = 1;
                for idx=stc_significant_ev_idx
                    % plot eigen vector
                    ax = subplot(r,1,cnt,'Parent', fig);
                    plot(ax, reshape(u(:,idx),app.sta_num_samples,[]))
                    ylabel(ax, sprintf('eig. vector %d',idx))
                    box(ax, 'off')
                    
%                     % plot histogram of score
%                     ax = subplot(num_sig_ev,2,2*cnt,'Parent', fig);
%                     
%                     
    
                    cnt = cnt + 1;
                end
            else
                fig.Visible = 'off';
            end

            %% plot scores of sig eigen vectors
            fig = app.STC_Panel_score;
            
            if num_sig_ev >0
                fig.Visible = 'on';
                fig.AutoResizeChildren = 'off';
                
                score = app.stc_score{app.selected_channel_index};
                
                r = max(num_sig_ev,2);
                cnt = 1;
                for idx=stc_significant_ev_idx
                    % plot eigen vector
                    ax = subplot(r,1,cnt,'Parent', fig);
                    histogram(ax, score(:,idx));
                    %ylabel(ax, sprintf('eig. vec. %d',idx))
                    xlabel(ax, sprintf('score for eig. vec. %d',idx))
                    ylabel(ax, 'count')
                    box(ax, 'off')
                    
%                     % plot histogram of score
%                     ax = subplot(num_sig_ev,2,2*cnt,'Parent', fig);
%                     
%                     
    
                    cnt = cnt + 1;
                end
                
            else
                fig.Visible = 'off';
            end


        end
        
        function plot_stc_one_eig_vecor(app, ax, u)
            plot(ax, u)
            
            axis(ax, 'off')
            
        end
%         function results = plot_STA(app, channel_index)
%             %app.infoTextArea.Value = ['function called with '  channel_name]
%             plot(app.STA_Axes_temporal, [1 2 3 4], [1 2 3 4], '-or')
%             
%         end
    
        function str = print_results(app)
            str = sprintf('ch name=%s',  app.channel_names{app.selected_channel_index});
            str = [str ', ' sprintf('ch index=%d', app.selected_channel_index)];
            
            app.infoTextArea.Value = str;
        end
        

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            
            % load channel names from the workspace
            app.channel_names = evalin('base','channel_names');
            app.channelListBox.Items = app.channel_names;
            app.channelListBox.ItemsData = 1:length(app.channel_names);


%             % load spike train data from the workspace
%             app.stim = evalin('base','stim');
%             
%             % load spike train data from the workspace
%             app.spike_train = evalin('base','spike_train');
           
            % load sta results for all channels
            app.sta_all_channels = evalin('base','sta_all_channels');
            app.sta_num_samples = evalin('base','sta_num_samples');
            app.height = sqrt(size(app.sta_all_channels{1},2)/app.sta_num_samples);
            app.width = app.height
            
            % sta param
            %app.sta_num_samples = 20;
            
            app.sampling_rate = evalin('base', 'sampling_rate');
            app.gridT = (-app.sta_num_samples+1:0)/app.sampling_rate;
            
            
            % load stc results
            app.stc_u = evalin('base', 'stc_u');
            app.stc_ev = evalin('base', 'stc_ev');
            app.stc_significant_ev_idx = evalin('base', 'stc_significant_ev_idx');
            
            app.stc_score = evalin('base', 'stc_score');
            
            
            app.infoTextArea.Value = 'Ready to run!';
            
            
            % run for the first imte
            app.selected_channel_index = 1;
            
            %plot_spike_train(app)
            
            % plot STA
            plot_sta(app)
            
            % plot STC
            plot_stc(app)
            
        end

        % Value changed function: channelListBox
        function channelListBoxValueChanged(app, event)
            %% get selected channel index
            app.selected_channel_index = app.channelListBox.Value;
            
            % clear info
            app.infoTextArea.Value = '';
            
            % plot spike train
            %plot_spike_train(app)
            
            % plot sta
            plot_sta(app)
                                    
            % plot stc
            plot_stc(app)
            
%             % print results
%             print_results(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GUIforSTASTCUIFigure = uifigure('Visible', 'off');
            app.GUIforSTASTCUIFigure.Name = 'GUI for STA & STC';
            app.channelListBoxLabel = uilabel(app.GUIforSTASTCUIFigure);
            app.channelListBoxLabel.Text = 'channel';
            app.channelListBox = uilistbox(app.GUIforSTASTCUIFigure);
            app.channelListBox.ValueChangedFcn = createCallbackFcn(app, @channelListBoxValueChanged, true);
            app.TabGroup = uitabgroup(app.GUIforSTASTCUIFigure);
            app.STATab = uitab(app.TabGroup);
            app.STATab.Title = 'STA';
            app.STA_Axes_temporal = uiaxes(app.STATab);
            title(app.STA_Axes_temporal, 'temporal')
            xlabel(app.STA_Axes_temporal, 'time prior to spike onset (ms)')
            ylabel(app.STA_Axes_temporal, 'stim intensity')
            app.STA_Axes_hist = uiaxes(app.STATab);
            title(app.STA_Axes_hist, 'histogram')
            xlabel(app.STA_Axes_hist, 'sta')
            ylabel(app.STA_Axes_hist, 'count')
            app.STA_Panel_slice = uipanel(app.STATab);
            app.STA_Panel_slice.Title = 'spatial';
            app.STCTab = uitab(app.TabGroup);
            app.STCTab.Title = 'STC';
            app.STC_Axes_ev = uiaxes(app.STCTab);
            title(app.STC_Axes_ev, '')
            xlabel(app.STC_Axes_ev, 'index')
            ylabel(app.STC_Axes_ev, 'eigen value')
            app.STC_Axes_hist = uiaxes(app.STCTab);
            title(app.STC_Axes_hist, 'histogram')
            xlabel(app.STC_Axes_hist, 'eigen value')
            ylabel(app.STC_Axes_hist, 'count')
            app.STC_Panel_sig_ev = uipanel(app.STCTab);
            app.STC_Panel_sig_ev.Title = 'significant eigen vectors';
            app.STC_Panel_score = uipanel(app.STCTab);
            app.STC_Panel_score.Title = 'score';
            app.infoTab = uitab(app.TabGroup);
            app.infoTab.Title = 'info';
            app.infoTextArea = uitextarea(app.GUIforSTASTCUIFigure);
            % Show the figure after all components are created
            app.GUIforSTASTCUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = stc_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GUIforSTASTCUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GUIforSTASTCUIFigure)
        end
    end
end